classdef Quadcopter_Plots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SelectFileButton            matlab.ui.control.Button
        SelectPlotDropDown          matlab.ui.control.DropDown
        SelectPlotDropDownLabel     matlab.ui.control.Label
        FileSelectedEditField       matlab.ui.control.EditField
        FileSelectedEditFieldLabel  matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes)
            ftdRaw = getFlightTestData(app.FileSelectedEditField.Value, 8, 587);
            ftdRaw.time = datetime(ftdRaw.time,'Format', 'u::DDD:HH:mm:ss.SS');
            switch app.SelectPlotDropDown.Value
                case "Accel"
                    plotIMUaccels_app(app,ftdRaw.time, [ftdRaw.accX_body ftdRaw.accY_body ftdRaw.accZ_body])
                case "Angle Rates"
                    plotAngRates_app(app,ftdRaw.time, [ftdRaw.p ftdRaw.q ftdRaw.r])
            end
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');
            app.FileSelectedEditField.Value = fullfile(path,file);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot!';
            app.FileSelectedEditFieldLabel = uilabel(app.UIFigure);
            app.FileSelectedEditFieldLabel.Text = 'File Selected';
            app.FileSelectedEditField = uieditfield(app.UIFigure, 'text');
            app.SelectPlotDropDownLabel = uilabel(app.UIFigure);
            app.SelectPlotDropDownLabel.Text = 'Select Plot';
            app.SelectPlotDropDown = uidropdown(app.UIFigure);
            app.SelectPlotDropDown.Items = {'Accel', 'Angle Rates'};
            app.SelectPlotDropDown.Value = 'Accel';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Quadcopter_Plots

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end